classdef RefineTraceSegDr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure  matlab.ui.Figure
        YpositionLabel          matlab.ui.control.Label
        XpositionLabel          matlab.ui.control.Label
        DataSave                matlab.ui.control.Button
        DataInfo                matlab.ui.control.TextArea
        DataInfoLabel           matlab.ui.control.Label
        TimeRangeTextArea       matlab.ui.control.TextArea
        TimeRangeTextAreaLabel  matlab.ui.control.Label
        LoadData                matlab.ui.control.Button
        traceTable              matlab.ui.control.Table
        TraceInfo               matlab.ui.control.TextArea
        TraceInfoTextAreaLabel  matlab.ui.control.Label
        Segmentation            matlab.ui.control.Button
        Intensity               matlab.ui.control.UIAxes
        y2time                  matlab.ui.control.UIAxes
        x2time                  matlab.ui.control.UIAxes
        MSDupdate               matlab.ui.control.UIAxes
        MSDall                  matlab.ui.control.UIAxes
        TrajUpdate              matlab.ui.control.UIAxes
        FLtraj                  matlab.ui.control.UIAxes
        BFtraj                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data % To save the data in the app
        Timerange = 200 % initialize the timerange
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Intensity, LoadData
        function LoadDataButtonPushed(app, event)
            % Open a dialog to select a file
            [file, path] = uigetfile({'*.mat', 'Data Files ( *.mat)'});
            if isequal(file, 0)
                % User canceled the file selection
                disp('User canceled file selection.');
            else
                % Construct the full file path
                fullFileName = fullfile(path, file);
                disp(['User selected ', fullFileName]);
                data = load(fullFileName); % Load .mat file
                app.Data.Trace = data; % Store the trajectory data (may need to adapt this based on your .mat structure)
                app.Data.Filename = fullFileName; % save the path and file name for the trajectories
                app.Data.Traceid = 1;
                % display the information of the data in the Data info
                app.DataInfo.Value = fullFileName;
                % generate a table for indexing and segmentation
                TraceNumber = length(data.TrackRefine);
                for ii = 1 : TraceNumber
                    Traceii = data.TrackRefine{ii,1};
                    TraceTable(ii,1) = ii;
                    TraceTable(ii,2) = Traceii.Coord(end,1)-Traceii.Coord(1,1);
                    if isfield(Traceii,'Seg')
                        TraceTable(ii,3) = 1;
                    else
                        TraceTable(ii,3) = 0;
                    end
                end
                app.Data.TraceTable = TraceTable;
                % visulize the table on the right
                app.traceTable.Data=TraceTable;
                % initialize the figures with the first trace
                TraceTemp = data.TrackRefine{1,1};
                T = TraceTemp.Coord(:,1)*TraceTemp.ExpT/1000; % time in second
                Coords = TraceTemp.Coord(:,8:9);
                Intensity = TraceTemp.Coord(:,5);
                Msd = TraceTemp.s_MSD;
                BFimage = TraceTemp.ROIBFrot;
                FLimage = TraceTemp.ROIFLrot;
                ROIRotate = TraceTemp.ROIRotate;
                ROIRecRotate = TraceTemp.ROIRecRotate;
                TraceID = TraceTemp.TraceId;
                Trace = TraceTemp.Coord;
                % plot the BF image with the trace

                % calculate the size of BF and FL image
                BFWidth = range(ROIRecRotate(:,1))*1.4;
                BFHeight = range(ROIRecRotate(:,2))*2;
                [BFH, BFW, ~] = size(BFimage);

                % Define the new axis limits so that the center of the image is [0, 0]
                xRange = (-BFWidth/2):(BFWidth/2);
                yRange = (-BFHeight/2):(BFHeight/2);
                BFimageP = BFimage(round((BFH-BFHeight)/2):round((BFH-BFHeight)/2+BFHeight),round((BFW-BFWidth)/2):round((BFW-BFWidth)/2+BFWidth));
                % subplot 1: The BF image with ROI and trajectory overlaid
                hold(app.BFtraj,"off");
                imshow(BFimageP,[], 'XData', xRange, 'YData', yRange,'InitialMagnification','fit','Parent',app.BFtraj);
                hold(app.BFtraj,"on");
                plot(app.BFtraj,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
                plot(app.BFtraj,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1.5,'color','b')
                plot(app.BFtraj,Trace(:,6),Trace(:,7),'-','linewidth',1.5,'color','y')
                set(app.BFtraj,'YDir','normal')
                
                % plot the FL image with the trace
                FLimageP = FLimage(round((BFH-BFHeight)/2):round((BFH-BFHeight)/2+BFHeight),round((BFW-BFWidth)/2):round((BFW-BFWidth)/2+BFWidth));
                % subplot 2: The FL image with ROI and trajectory overlaid
                hold(app.FLtraj,"off");
                imshow(FLimageP,[], 'XData', xRange, 'YData', yRange,'InitialMagnification','fit','Parent',app.FLtraj);
                hold(app.FLtraj,"on");
                plot(app.FLtraj,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
                plot(app.FLtraj,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1.5,'color','y')
                plot(app.FLtraj,Trace(:,6),Trace(:,7),'-','linewidth',1.5,'color','c')
                set(app.FLtraj,'YDir','normal')
                
                % subplot 3: The ROI with trajectory color coded with time
                plot(app.TrajUpdate,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
                hold(app.TrajUpdate,'on');
                plot(app.TrajUpdate,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1,'color','b')

                % plot the median trajectory in color copied from Josh-time
                % plot the median trajectory in color copied from Josh
                xS = [Trace(:,6) ,Trace(:,6)];
                yS = [Trace(:,7) ,Trace(:,7)];
                zS = zeros(size(xS));
                cS = [T-min(T),T-min(T)];
                surf(app.TrajUpdate,xS,yS,zS,cS,'EdgeColor','interp'); %// color binded to "y" values
                %                 view(2)
                colormap(app.TrajUpdate,'jet')
                set(app.TrajUpdate,'Linewidth',1.5)
                xlim(app.TrajUpdate,[min(ROIRecRotate(:,1))*1.1, max(ROIRecRotate(:,1))*1.1])
                ylim(app.TrajUpdate,[min(ROIRecRotate(:,2))*1.2, max(ROIRecRotate(:,2))*1.2])

                % plot the Intensity
                hold(app.Intensity,'off');
                plot(app.Intensity,T,Intensity(:,1),'-o','linewidth',1,'color','b');
                set(gca, 'FontSize', 15);
                xlabel('Time (s)', 'FontSize', 12);
                ylabel('Intensity (photons)', 'FontSize',12);
              
                % plot the MSD
                errorbar(app.MSDall,Msd(:,1),Msd(:,2),Msd(:,4))
                set(gca, 'FontSize', 15);
                Trange = app.Timerange*TraceTemp.ExpT/1000;
                % plot the X position against the time
                hold(app.x2time,'off');
                xS = [T, T];
                yS = [Coords(:,1), Coords(:,1)];
                zS = zeros(size(xS));
                cS = [T-min(T), T-min(T)];
                hs = surf(app.x2time, xS, yS, zS, cS, 'EdgeColor', 'interp');
                view(app.x2time, 2);
                colormap(app.x2time, 'jet');
                caxis(app.x2time, [min(cS(:,1)), max(cS(:,1))]);
                set(hs, 'LineWidth', 1.5);
                hold(app.x2time, 'on');
                plot(app.x2time, T, Coords(:,1), 'ok', 'MarkerSize', 4, 'LineWidth', 1.5);
                % ylabel(app.x2time, 'X-position(nm)');
                xlim(app.x2time, [0 - 0.01 * Trange, 1.01 * Trange]);
                app.x2time.YRuler.TickLabelGapOffset = -30;
                
                hold(app.y2time,'off');
                xS = [T, T];
                yS = [Coords(:,2), Coords(:,2)];
                zS = zeros(size(xS));
                cS = [T-min(T), T-min(T)];
                hs = surf(app.y2time, xS, yS, zS, cS, 'EdgeColor', 'interp');
                view(app.y2time, 2);
                colormap(app.y2time, 'jet');
                caxis(app.y2time, [min(cS(:,1)), max(cS(:,1))]);
                set(hs, 'LineWidth', 1.5);
                hold(app.y2time, 'on');
                plot(app.y2time, T, Coords(:,2), 'ok', 'MarkerSize', 4, 'LineWidth', 1.5);
                % ylabel(app.y2time,'Y-position(nm)');
                
                xlim(app.y2time,[0-0.01*Trange,1.01*Trange])
                app.y2time.YRuler.TickLabelGapOffset = -30;
%                 xlim(app.x2time,[min(T)-0.05*(max(T)-min(T)) max(T)+0.05*(max(T)-min(T))])


            end
        end

        % Callback function: traceTable, x2time
        function traceTableCellSelection(app, event)
            indices = event.Indices;
            Traceid = indices(1); % the index of selected trace
            % load data
            Data = app.Data.Trace; % get all the trace
            TraceTable = app.Data.TraceTable; % get the trace table
            app.Data.Traceid = Traceid;
            % update figures
            TraceTemp = Data.TrackRefine{Traceid,1};
            T = TraceTemp.Coord(:,1)*TraceTemp.ExpT/1000; % time in second
            Coords = TraceTemp.Coord(:,8:9);
            Intensity = TraceTemp.Coord(:,5);
            Msd = TraceTemp.s_MSD;
            BFimage = TraceTemp.ROIBFrot;
            FLimage = TraceTemp.ROIFLrot;
            ROIRotate = TraceTemp.ROIRotate;
            ROIRecRotate = TraceTemp.ROIRecRotate;
            Trace = TraceTemp.Coord;
            % plot the BF image with the trace

            % calculate the size of BF and FL image
            BFWidth = round(range(ROIRecRotate(:,1))*1.4);
            BFHeight = round(range(ROIRecRotate(:,2))*2);
            [BFH, BFW, ~] = size(BFimage);

            % Define the new axis limits so that the center of the image is [0, 0]
            xRange = (-BFWidth/2):(BFWidth/2);
            yRange = (-BFHeight/2):(BFHeight/2);
            BFimageP = BFimage(round((BFH-BFHeight)/2):round((BFH-BFHeight)/2+BFHeight),round((BFW-BFWidth)/2):round((BFW-BFWidth)/2+BFWidth));

            % subplot 1: The BF image with ROI and trajectory overlaid
            hold(app.BFtraj,"off");
            imshow(BFimageP,[], 'XData', xRange, 'YData', yRange,'InitialMagnification','fit','Parent',app.BFtraj);
            hold(app.BFtraj,"on");
            plot(app.BFtraj,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
            plot(app.BFtraj,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1.5,'color','b')
            plot(app.BFtraj,Trace(:,6),Trace(:,7),'-','linewidth',1.5,'color','y')
            set(app.BFtraj,'YDir','normal')
            
            % plot the FL image with the trace
            FLimageP = FLimage(round((BFH-BFHeight)/2):round((BFH-BFHeight)/2+BFHeight),round((BFW-BFWidth)/2):round((BFW-BFWidth)/2+BFWidth));

            % subplot 2: The FL image with ROI and trajectory overlaid
            hold(app.FLtraj,'off');
            imshow(FLimageP,[], 'XData', xRange, 'YData', yRange,'InitialMagnification','fit','Parent',app.FLtraj);
            hold(app.FLtraj,"on");
            plot(app.FLtraj,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
            plot(app.FLtraj,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1.5,'color','y')
            plot(app.FLtraj,Trace(:,6),Trace(:,7),'-','linewidth',1.5,'color','c')
            set(app.FLtraj,'YDir','normal')

            % subplot 3: The ROI with trajectory color coded with time
            hold(app.TrajUpdate,'off');
            plot(app.TrajUpdate,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
            hold(app.TrajUpdate,'on');
            plot(app.TrajUpdate,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1,'color','b')

            % plot the median trajectory in color copied from Josh-time
            % plot the median trajectory in color copied from Josh
            xS = [Trace(:,6) ,Trace(:,6)];
            yS = [Trace(:,7) ,Trace(:,7)];
            zS = zeros(size(xS));
            cS = [T-min(T),T-min(T)];
            surf(app.TrajUpdate,xS,yS,zS,cS,'EdgeColor','interp'); %// color binded to "y" values
            %                 view(2)
            colormap(app.TrajUpdate,'jet')
            set(app.TrajUpdate,'Linewidth',1.5)
            xlim(app.TrajUpdate,[min(ROIRecRotate(:,1))*1.1, max(ROIRecRotate(:,1))*1.1])
            ylim(app.TrajUpdate,[min(ROIRecRotate(:,2))*1.2, max(ROIRecRotate(:,2))*1.2])

            % plot the Intensity
            hold(app.Intensity,'off');
            plot(app.Intensity,T,Intensity(:,1),'-o','linewidth',1,'color','b');
            set(gca, 'FontSize', 15);
            xlabel('Time (s)', 'FontSize', 12);
            ylabel('Intensity (photons)', 'FontSize',12);

            % plot the MSD
            errorbar(app.MSDall,Msd(:,1),Msd(:,2),Msd(:,4))

            % plot the X-Y position against the time
            Trange = app.Timerange*TraceTemp.ExpT/1000;
                hold(app.x2time,'off');
                xS = [T, T];
                yS = [Coords(:,1), Coords(:,1)];
                zS = zeros(size(xS));
                cS = [T-min(T), T-min(T)];
                hs = surf(app.x2time, xS, yS, zS, cS, 'EdgeColor', 'interp');
                view(app.x2time, 2);
                colormap(app.x2time, 'jet');
                caxis(app.x2time, [min(cS(:,1)), max(cS(:,1))]);
                set(hs, 'LineWidth', 1.5);
                hold(app.x2time, 'on');
                plot(app.x2time, T, Coords(:,1), 'ok', 'MarkerSize', 4, 'LineWidth', 1.5);
                % ylabel(app.x2time, 'X-position(nm)');
                xlim(app.x2time, [0 - 0.01 * Trange, 1.01 * Trange]);

                hold(app.y2time,'off');
                xS = [T, T];
                yS = [Coords(:,2), Coords(:,2)];
                zS = zeros(size(xS));
                cS = [T-min(T), T-min(T)];
                hs = surf(app.y2time, xS, yS, zS, cS, 'EdgeColor', 'interp');
                view(app.y2time, 2);
                colormap(app.y2time, 'jet');
                caxis(app.y2time, [min(cS(:,1)), max(cS(:,1))]);
                set(hs, 'LineWidth', 1.5);
                hold(app.y2time, 'on');
                plot(app.y2time, T, Coords(:,2), 'ok', 'MarkerSize', 4, 'LineWidth', 1.5);
                % ylabel(app.y2time,'Y-position(nm)');
                xlim(app.y2time,[0-0.01*Trange,1.01*Trange])

            % update the information of current trace
            TraceCurrent = ['Trace ' num2str(Traceid) ' is selected.   Trace name is ' TraceTemp.TraceId '.'];
            app.TraceInfo.Value = TraceCurrent;
        end

        % Button pushed function: Segmentation
        function SegmentationButtonPushed(app, event)
            if ~isfield(app.Data,'Trace') % get all the trace
                errordlg('You must load a TrackRefine data set');
            else
                Data = app.Data.Trace; % get all the trace
                TraceTable = app.Data.TraceTable; % get the trace table
                Traceid = app.Data.Traceid; % get the current trace id.
                % update figures
                TraceTemp = Data.TrackRefine{Traceid,1};
                %get all the information
                T = TraceTemp.Coord(:,1)*TraceTemp.ExpT/1000; % time in second
                Coords = TraceTemp.Coord(:,8:9);
                frame = TraceTemp.Coord(:,1);
                Intensity = TraceTemp.Coord(:,5);
                Msd = TraceTemp.s_MSD;
                BFimage = TraceTemp.ROIBFrot;
                FLimage = TraceTemp.ROIFLrot;
                ROIRotate = TraceTemp.ROIRotate;
                ROIRecRotate = TraceTemp.ROIRecRotate;
                Trace = TraceTemp.Coord;
                % start segmentation
                Istate = 1; % state counter
                EndFlag = 1; % the flag to end the selection
                Seg = []; % initialize the segmentation record
                % classify the cell stage
                choice = questdlg('Classify cell stage:', ...
                                'what type of the cell is', ...
                                 'monocell', 'diplocell', 'diplocell');

               % label the cell stage, 1 means stage 1-2:
               % monocell, 2 means stage 3-5: diderm
               switch choice
                   case 'monocell'
                      CellStage = 1; % stage 1-2
                   case 'diplocell'
                      CellStage = 2; % stage 3-5
               end

                while EndFlag ~= 0
                    response=questdlg(['Select the ' num2str(Istate) 'state in the trace'], ...
                        'Select a state?', 'Yes' , 'No', 'Yes');
                    if strcmp(response,'Yes')
                        SelectFlag = 0;  % flag for saving one selection
                        while SelectFlag == 0
                            %segment the data
                            [X Y] = ginput(2);
                            X1 = min(X);
                            X2 = max(X);
                            % find index based on time
                            IndexT = find (T>= X1 & T <= X2);
                            TimeT_TXY = T(IndexT);
                            % crop the segment, unit in nm
                            TraceTx = Trace(IndexT,8);
                            TraceTy = Trace(IndexT,9);
                            % fit the x and y speed
                            [FitTraceX, pX, DisplX, StDX, RatioX] = linfitR(TimeT_TXY,TraceTx);
                            Vx = pX(1);
                            Rx = RatioX;
                            StDX = StDX;
                            DisplX = DisplX;
                            [FitTraceY, pY, DisplY, StDY, RatioY] = linfitR(TimeT_TXY,TraceTy);
                            Vy = pY(1);
                            Ry = RatioY;
                            StDY = StDY;
                            DisplY = DisplY;

                            % update the Intensity
                            hold(app.Intensity,'off');
                            IT = TimeT_TXY;
                            IntensitySubset = Intensity(IndexT, 1);
                            plot(app.Intensity,IT,IntensitySubset,'-o','linewidth',1,'color','b');

                            % plot the X-Y position against the time and
                            % fit results
                            hold(app.x2time, "on");
                            hx1 = plot(app.x2time, TimeT_TXY, FitTraceX, '-k');
                            numstrVx = {['V= ' num2str(pX(1),2)],['R=' num2str(RatioX,2)]};
                            hl1 = xline(app.x2time, X1, 'k', 'Label', numstrVx, ...
                                'LabelOrientation', 'horizontal', ...    
                                'LabelHorizontalAlignment', 'right', ...
                                'LabelVerticalAlignment', 'top', ... 
                                'FontSize', 9, 'FontWeight', 'bold', 'Color', 'k');
                            h12 = xline(app.x2time, X2, 'k');

                            hold(app.y2time, "on");
                            % plot(app.y2time, T, Coords(:, 2), 'color', 'r', 'Marker', 'o');
                            hy1 = plot(app.y2time, TimeT_TXY, FitTraceY, '-k');
                            numstrVy = {['V= ' num2str(pY(1),2)],['R=' num2str(RatioY,2)]};
                            h13 = xline(app.y2time, X1, 'k', 'Label', numstrVy, ...
                                'LabelOrientation', 'horizontal', ...
                                'LabelHorizontalAlignment', 'right', ...
                                'LabelVerticalAlignment', 'top', ...
                                'FontSize', 9, 'FontWeight', 'bold', 'Color', 'k');
                            h14 = xline(app.y2time, X2, 'k');
                         
                            % plot the seg region and speed


                            %update the trace image
                            hold(app.TrajUpdate,'off');
                            plot(app.TrajUpdate,ROIRotate(:,1),ROIRotate(:,2),':','linewidth',2,'color','r')
                            hold(app.TrajUpdate,'on');
                            plot(app.TrajUpdate,ROIRecRotate(:,1),ROIRecRotate(:,2),'-','linewidth',1,'color','b')
                            xS = [Trace(IndexT,6) ,Trace(IndexT,6)];
                            yS = [Trace(IndexT,7) ,Trace(IndexT,7)];
                            zS = zeros(size(xS));
                            cS = [IndexT-min(IndexT),IndexT-min(IndexT)];
                            surf(app.TrajUpdate,xS,yS,zS,cS,'EdgeColor','interp'); %// color binded to "y" values
                            %                 view(2)
                            colormap(app.TrajUpdate,'jet')
                            set(app.TrajUpdate,'Linewidth',1.5)
                            xlim(app.TrajUpdate,[min(ROIRecRotate(:,1))*1.1, max(ROIRecRotate(:,1))*1.1])
                            ylim(app.TrajUpdate,[min(ROIRecRotate(:,2))*1.2, max(ROIRecRotate(:,2))*1.2])
                            
                            %update the MSD plot
                            % calculate the MSD of the region
                            s_MSD = MSDsingle2D(Trace(IndexT,1:3),TraceTemp.Pixelsize/1000,TraceTemp.ExpT/1000,TraceTemp.InterT/1000);
                            errorbar(app.MSDupdate,s_MSD(:,1),s_MSD(:,2),s_MSD(:,4));

                            % ask whether it is okay to select this
                            response=questdlg(['Is this selection okay?'], ...
                                'Check', 'Yes' , 'No','Yes');
                            if strcmp(response,'Yes')
                                % label the trace as finished
                                SelectFlag = 1;
                                % save the segmenation and label the trace as segmented
                                Seg(Istate,1) = TimeT_TXY(1); % start time
                                Seg(Istate,2) = TimeT_TXY(end); % end time
                                Seg(Istate,3) = range(TimeT_TXY); % length
                                Seg(Istate,4) = Vx; %Speed on X axis
                                Seg(Istate,5) = Vy; %Speed on Y axis
                                Seg(Istate,6) = sqrt(Vx^2+Vy^2); % overall speed

                                % Save segmented trajectory details
                                SegmentCoords = Trace(IndexT, 1:2); % Extract coordinates for this segment
                                SegmentIntensity = Intensity(IndexT, 1); % Extract intensity for this segment
                                IT = TimeT_TXY; % Time subset (still required for Intensity plot)

                                % Save into UnifiedSeg structure
                                UnifiedSeg(Istate).StartTime = TimeT_TXY(1); % Start time
                                UnifiedSeg(Istate).EndTime = TimeT_TXY(end); % End time
                                UnifiedSeg(Istate).Length = range(TimeT_TXY); % Segment duration
                                UnifiedSeg(Istate).TimeT_TXY = TimeT_TXY;
                                UnifiedSeg(Istate).TraceTx = TraceTx;
                                UnifiedSeg(Istate).TraceTy = TraceTy;
                                UnifiedSeg(Istate).Vx = Vx; % Speed on X axis
                                UnifiedSeg(Istate).Vy = Vy; % Speed on Y axis
                                UnifiedSeg(Istate).Rx = Rx;
                                UnifiedSeg(Istate).Ry = Ry;
                                UnifiedSeg(Istate).StDX = StDX;
                                UnifiedSeg(Istate).StDY = StDY;
                                UnifiedSeg(Istate).StDXY = sqrt(StDX^2 + StDY^2);
                                UnifiedSeg(Istate).Rxy = sqrt(StDX^2 + StDY^2) / sqrt(DisplX^2 + DisplY^2);
                                UnifiedSeg(Istate).OverallSpeed = sqrt(Vx^2 + Vy^2); % Overall speed
                                UnifiedSeg(Istate).Coords = SegmentCoords; % Segment coordinates
                                UnifiedSeg(Istate).IntensityValues = SegmentIntensity; % Intensity values
                                UnifiedSeg(Istate).MSD = s_MSD; % Recalculated MSD
                                UnifiedSeg(Istate).CellStage = CellStage; % Stage 1-2
                                Seg(Istate,7) = CellStage; % the cell is in stage 1-2
                                % determine the trajectory location:
                                % 1-peripheral
                                % 2-on S0 (old septum
                                % 3-on the front edge of S1
                                % 4-on the base (triangle region) of S1
                                options = {'Peripheral', 'S0', 'S1-leading', 'S1-base'};

                                [index, tf] = listdlg('PromptString', 'Select the cell position:', 'SelectionMode', 'single', 'ListString', options);
                                % assign the trajectory's position
                                UnifiedSeg(Istate).Location = index; % Trajectory location
                                Seg(Istate,8) = index; % the location of the trajectory in side the cell
                                Istate = Istate+1; % state counter
                                
                            else
                                delete(hx1);
                                delete(hy1);
                                delete(hl1);
                                delete(h12);
                                delete(h13);
                                delete(h14);                               
                            end
                        end
                       else
                            EndFlag = 0;
                    end
                    if ~isempty(Seg)
                        TraceTable(Traceid,3) = 1;
                        app.Data.TraceTable = TraceTable;
                        app.traceTable.Data=TraceTable;
                        TraceTemp.Seg = UnifiedSeg;
                        Data.TrackRefine{Traceid,1}= TraceTemp;
                        app.Data.Trace = Data;
                    end
                end
            end
                       
        end

        % Value changed function: TimeRangeTextArea
        function TimeRangeTextAreaValueChanged(app, event)
            value = app.TimeRangeTextArea.Value
            app.Timerange = str2num(value{1,1});
        end

        % Button pushed function: DataSave
        function DataSaveButtonPushed(app, event)
            % save the data to the same file
            fullFileName =app.Data.Filename;
            Data = app.Data.Trace;
            TrackRefine = Data.TrackRefine;
            for i = 1:length(TrackRefine)
                if isfield(TrackRefine{i}, 'UnifiedSeg')
                    TrackRefine{i}.Seg = TrackRefine{i}.UnifiedSeg;
                end
            end
            save(fullFileName, "TrackRefine");
            display(["The tracks in " fullFileName " have been segmented and update!"]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure = uifigure('Visible', 'off');
            app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure.Name = 'Segment single-molecule trajectories For Deinococcus Cells';
            app.BFtraj = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.BFtraj, {'BF Image'; ''})
            zlabel(app.BFtraj, 'Z')
            app.BFtraj.XTick = [];
            app.BFtraj.YTick = [];
            app.BFtraj.Tag = 'BFtraj';
            app.FLtraj = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.FLtraj, {'FL Image'; ''})
            zlabel(app.FLtraj, 'Z')
            app.FLtraj.XTick = [];
            app.FLtraj.YTick = [];
            app.FLtraj.Tag = 'BFtraj';
            app.TrajUpdate = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.TrajUpdate, {'Updated Trace'; ''})
            zlabel(app.TrajUpdate, 'Z')
            app.TrajUpdate.XTick = [];
            app.TrajUpdate.YTick = [];
            app.TrajUpdate.Tag = 'BFtraj';
            app.MSDall = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.MSDall, {'MSD of the trace'; ''})
            xlabel(app.MSDall, 'Lagtime(s)')
            ylabel(app.MSDall, 'MSD(um^2/s)')
            zlabel(app.MSDall, 'Z')
            app.MSDall.Tag = 'BFtraj';
            app.MSDupdate = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.MSDupdate, {'MSD of the segment'; ''})
            xlabel(app.MSDupdate, 'Lagtime(s)')
            ylabel(app.MSDupdate, 'MSD(um^2/s)')
            zlabel(app.MSDupdate, 'Z')
            app.MSDupdate.Tag = 'BFtraj';
            app.x2time = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            xlabel(app.x2time, 'Time')
            zlabel(app.x2time, 'Z')
            app.x2time.ButtonDownFcn = createCallbackFcn(app, @traceTableCellSelection, true);
            app.x2time.Tag = 'BFtraj';
            app.y2time = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            xlabel(app.y2time, 'Time')
            zlabel(app.y2time, 'Z')
            app.y2time.Tag = 'BFtraj';
            app.Intensity = uiaxes(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            title(app.Intensity, {'Intensity'; ''})
            xlabel(app.Intensity, 'Time(frame)')
            ylabel(app.Intensity, 'Intensity(photons)')
            zlabel(app.Intensity, 'Z')
            app.Intensity.ButtonDownFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.Intensity.Tag = 'BFtraj';
            app.Segmentation = uibutton(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure, 'push');
            app.Segmentation.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.Segmentation.Tooltip = {'Segment the current selected trace'};
            app.Segmentation.Text = 'Segment';
            app.TraceInfoTextAreaLabel = uilabel(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.TraceInfoTextAreaLabel.Text = 'Trace Info';
            app.TraceInfo = uitextarea(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.traceTable = uitable(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.traceTable.ColumnName = {'Id'; 'L'; 'Seg?'};
            app.traceTable.RowName = {};
            app.traceTable.ColumnEditable = true;
            app.traceTable.CellSelectionCallback = createCallbackFcn(app, @traceTableCellSelection, true);
            app.LoadData = uibutton(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Tooltip = {'Select the .mat file generated from the traceInROI function. '; 'FL image required'};
            app.LoadData.Text = 'Load Trajectories';
            app.TimeRangeTextAreaLabel = uilabel(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.TimeRangeTextAreaLabel.Text = 'Time Range';
            app.TimeRangeTextArea = uitextarea(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.TimeRangeTextArea.ValueChangedFcn = createCallbackFcn(app, @TimeRangeTextAreaValueChanged, true);
            app.TimeRangeTextArea.Value = {'200'};
            app.DataInfoLabel = uilabel(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.DataInfoLabel.Text = 'Data Info';
            app.DataInfo = uitextarea(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.DataSave = uibutton(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure, 'push');
            app.DataSave.ButtonPushedFcn = createCallbackFcn(app, @DataSaveButtonPushed, true);
            app.DataSave.Tooltip = {'Segment the current selected trace'};
            app.DataSave.Text = 'Save';
            app.XpositionLabel = uilabel(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.XpositionLabel.Text = 'X position';
            app.YpositionLabel = uilabel(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure);
            app.YpositionLabel.Text = 'Y position';
            % Show the figure after all components are created
            app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RefineTraceSegDr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentsinglemoleculetrajectoriesForDeinococcusCellsUIFigure)
        end
    end
end